classdef Reto4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SimulacinButton              matlab.ui.control.Button
        DemandValueEditField         matlab.ui.control.NumericEditField
        DemandValueEditFieldLabel    matlab.ui.control.Label
        DemandaSlider                matlab.ui.control.Slider
        DemandaSliderLabel           matlab.ui.control.Label
        EolicValueEditField          matlab.ui.control.NumericEditField
        EolicValueEditFieldLabel     matlab.ui.control.Label
        EolicoSlider                 matlab.ui.control.Slider
        EolicoSliderLabel            matlab.ui.control.Label
        SolarValueEditField          matlab.ui.control.NumericEditField
        SolarValueEditFieldLabel     matlab.ui.control.Label
        SolarSlider                  matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        AlumbradoPublicoLamp         matlab.ui.control.Lamp
        BombeoaguaLamp               matlab.ui.control.Lamp
        BombeoaguaLampLabel          matlab.ui.control.Label
        AlumbradoPublicoLampLabel    matlab.ui.control.Label
        EscuelaLamp                  matlab.ui.control.Lamp
        EscuelaLampLabel             matlab.ui.control.Label
        EstadoSoCGaugeLabel          matlab.ui.control.Label
        EstadoSoCGauge               matlab.ui.control.Gauge
        SOCEditField                 matlab.ui.control.NumericEditField
        SOCEditFieldLabel            matlab.ui.control.Label
        CoberturadedemandaEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_2             matlab.ui.control.Label
        BalanceenergticokWEditField  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: SolarSlider
        function SolarSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SolarValueEditField.Value = changingValue;
            
        end

        % Value changing function: EolicoSlider
        function EolicoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EolicValueEditField.Value = changingValue;
        end

        % Value changing function: DemandaSlider
        function DemandaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DemandValueEditField.Value=changingValue;
        end

        % Button pushed function: SimulacinButton
        function SimulacinButtonPushed(app, event)
            
            solar = app.SolarValueEditField.Value;
            eolico= app.EolicValueEditField.Value;
            demanda = app.DemandValueEditField.Value;
            balance = (solar + eolico) - demanda;
            generacion_hibrida= solar + eolico;


            app.BalanceenergticokWEditField.Value=balance;
            valores = [solar, eolico, generacion_hibrida,   demanda, balance];
            b =  bar(app.UIAxes,valores);

            b.FaceColor='Flat';
            b.CData(1,:) =[1 0.7 0];
            b.CData(2,:) =[0.2 0.6 0.9];
            b.CData(3,:) =[0.87 0.33 0.0];
            b.CData(4,:) =[0.5 0.5 0.5];
            if balance>0
                b.CData(5,:) =[0.1 0.7 0.2];
            elseif balance<0
                b.CData(5,:)=[0.9 0.2 0.2];
            else 
                b.CData(5,:)=[0.5 0.5 0.5];
            end

            app.UIAxes.XTickLabel= {'Solar', 'Eolica', 'Generación Hibrida','Demanda', 'Balance'};
            app.UIAxes.Title.String = 'Balance energetico';
            app.UIAxes.Title.String= 'Potencia kW';
            app.UIAxes.Title.String = 'Variables energeticas';


            if demanda >0
                cobertura = ((solar + eolico)/ demanda)*100,100;
            else
                cobertura= 0;
            end
            app.CoberturadedemandaEditField.Value = cobertura;
            app.EstadoSoCGauge.Value= cobertura;

            SOC=50;
            bateria=100;

            SOC=((SOC+balance)/bateria)*100;
            if SOC>100;
                SOC=100;
            elseif SOC < 0;
                SOC= 0;
            end
            app.SOCEditField.Value=SOC;
            app.EstadoSoCGauge.Value=SOC;


            if SOC > 40;
                app.EscuelaLamp.Color = [0.0 1.0 0.0];
                app.BombeoaguaLamp.Color=[0.0 1.0 0.0];
                app.AlumbradoPublicoLamp.Color=[0.0 1.0 0.0];
            elseif SOC>20;
                app.EscuelaLamp.Color = [0.0 1.0 0.0];
                app.BombeoaguaLamp.Color=[0.0 1.0 0.0];
                app.AlumbradoPublicoLamp.Color=[1.0 0.0 0.0];
            else
                app.EscuelaLamp.Color = [0.0 1.0 0.0];
                app.BombeoaguaLamp.Color=[1.0 0.0 0.0];
                app.AlumbradoPublicoLamp.Color=[1.0 0.0 0.0];
            end


        end

        % Callback function
        function SoCSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SoCEditField.Value= changingValue;
        end

        % Callback function
        function SoCEditFieldValueChanged(app, event)
            value = app.SoCEditField.Value;
            app.SoCSlider.Value = value;
        end

        % Callback function
        function SoCEditFieldValueChanged2(app, event)
        
        end

        % Value changed function: SOCEditField
        function SOCEditFieldValueChanged(app, event)
            value = app.SOCEditField.Value;
            app.SOCEditField.Value=value;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Solar';
            app.SolarSlider = uislider(app.LeftPanel);
            app.SolarSlider.Limits = [0 150];
            app.SolarSlider.ValueChangingFcn = createCallbackFcn(app, @SolarSliderValueChanging, true);
            app.SolarValueEditFieldLabel = uilabel(app.LeftPanel);
            app.SolarValueEditFieldLabel.Text = 'Solar Value';
            app.SolarValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EolicoSliderLabel = uilabel(app.LeftPanel);
            app.EolicoSliderLabel.Text = 'Eolico';
            app.EolicoSlider = uislider(app.LeftPanel);
            app.EolicoSlider.ValueChangingFcn = createCallbackFcn(app, @EolicoSliderValueChanging, true);
            app.EolicValueEditFieldLabel = uilabel(app.LeftPanel);
            app.EolicValueEditFieldLabel.Text = 'EolicValue';
            app.EolicValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DemandaSliderLabel = uilabel(app.LeftPanel);
            app.DemandaSliderLabel.Text = 'Demanda';
            app.DemandaSlider = uislider(app.LeftPanel);
            app.DemandaSlider.Limits = [0 200];
            app.DemandaSlider.ValueChangingFcn = createCallbackFcn(app, @DemandaSliderValueChanging, true);
            app.DemandValueEditFieldLabel = uilabel(app.LeftPanel);
            app.DemandValueEditFieldLabel.Text = 'DemandValue';
            app.DemandValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SimulacinButton = uibutton(app.LeftPanel, 'push');
            app.SimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacinButtonPushed, true);
            app.SimulacinButton.Text = 'Simulación';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Balance Energético')
            xlabel(app.UIAxes, 'Variables energéticas')
            ylabel(app.UIAxes, 'Potencia (kW)')
            zlabel(app.UIAxes, 'Z')
            app.EditFieldLabel = uilabel(app.RightPanel);
            app.EditFieldLabel.Text = 'Balance energético (kW)';
            app.BalanceenergticokWEditField = uieditfield(app.RightPanel, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.RightPanel);
            app.EditFieldLabel_2.Text = 'Cobertura de demanda %';
            app.CoberturadedemandaEditField = uieditfield(app.RightPanel, 'numeric');
            app.CoberturadedemandaEditField.Enable = 'off';
            app.SOCEditFieldLabel = uilabel(app.RightPanel);
            app.SOCEditFieldLabel.Text = 'SOC';
            app.SOCEditField = uieditfield(app.RightPanel, 'numeric');
            app.SOCEditField.ValueChangedFcn = createCallbackFcn(app, @SOCEditFieldValueChanged, true);
            app.SOCEditField.Editable = 'off';
            app.SOCEditField.Value = 50;
            app.EstadoSoCGauge = uigauge(app.RightPanel, 'circular');
            app.EstadoSoCGaugeLabel = uilabel(app.RightPanel);
            app.EstadoSoCGaugeLabel.Text = 'Estado SoC %';
            app.EscuelaLampLabel = uilabel(app.RightPanel);
            app.EscuelaLampLabel.Text = 'Escuela';
            app.EscuelaLamp = uilamp(app.RightPanel);
            app.AlumbradoPublicoLampLabel = uilabel(app.RightPanel);
            app.AlumbradoPublicoLampLabel.Text = 'Alumbrado Publico';
            app.BombeoaguaLampLabel = uilabel(app.RightPanel);
            app.BombeoaguaLampLabel.Text = 'Bombeo agua';
            app.BombeoaguaLamp = uilamp(app.RightPanel);
            app.AlumbradoPublicoLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end